classdef project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        NghimTab                 matlab.ui.container.Tab
        NisuyTab                 matlab.ui.container.Tab
        NISUYLabel               matlab.ui.control.Label
        EditField                matlab.ui.control.EditField
        BcEditFieldLabel         matlab.ui.control.Label
        kqnsEditField            matlab.ui.control.EditField
        KtqunisuyEditFieldLabel  matlab.ui.control.Label
        TnsButton                matlab.ui.control.Button
        gtnsEditField            matlab.ui.control.EditField
        GitrnisuyEditFieldLabel  matlab.ui.control.Label
        dtnsEditField            matlab.ui.control.EditField
        ATHCLabel                matlab.ui.control.Label
        ppnsDropDown             matlab.ui.control.DropDown
        PHNGPHPLabel             matlab.ui.control.Label
        ynsEditField             matlab.ui.control.EditField
        yEditField_2Label        matlab.ui.control.Label
        xnsEditField             matlab.ui.control.EditField
        xEditField_2Label        matlab.ui.control.Label
        UIAxesNS                 matlab.ui.control.UIAxes
        HiquyTab                 matlab.ui.container.Tab
        DegreeEditField          matlab.ui.control.EditField
        DegreeEditFieldLabel     matlab.ui.control.Label
        TypeDropDown             matlab.ui.control.DropDown
        TypeDropDownLabel        matlab.ui.control.Label
        YDataEditField           matlab.ui.control.EditField
        YDataEditFieldLabel      matlab.ui.control.Label
        XDataEditField           matlab.ui.control.EditField
        XDataEditFieldLabel      matlab.ui.control.Label
        RunButton                matlab.ui.control.Button
        UIAxes                   matlab.ui.control.UIAxes
        derivativeTab            matlab.ui.container.Tab
        OHMLabel                 matlab.ui.control.Label
        resultEditField          matlab.ui.control.EditField
        KtquEditFieldLabel_2     matlab.ui.control.Label
        figureOutButton          matlab.ui.control.StateButton
        valueEditField           matlab.ui.control.NumericEditField
        GitrcntnhohmLabel        matlab.ui.control.Label
        methodDropDown           matlab.ui.control.DropDown
        PhngphpohmLabel          matlab.ui.control.Label
        errorOptionDropDown      matlab.ui.control.DropDown
        cnhpinputLabel_2         matlab.ui.control.Label
        yInputEditField          matlab.ui.control.EditField
        NhpmngyLabel             matlab.ui.control.Label
        xInputEditField          matlab.ui.control.EditField
        NhpmngxLabel             matlab.ui.control.Label
        typeOptionDropDown       matlab.ui.control.DropDown
        cnhpinputLabel           matlab.ui.control.Label
        TchphnTab                matlab.ui.container.Tab
        LununhphmvththmduchoccduvnhngngcchoknhaIloveYouLabel  matlab.ui.control.Label
        method_input             matlab.ui.control.DropDown
        MethodLabel              matlab.ui.control.Label
        OPTIONINPUTListBox       matlab.ui.control.ListBox
        OPTIONINPUTListBoxLabel  matlab.ui.control.Label
        yEditField               matlab.ui.control.EditField
        YEditFieldLabel          matlab.ui.control.Label
        xEditField               matlab.ui.control.EditField
        XEditFieldLabel          matlab.ui.control.Label
        Result                   matlab.ui.control.NumericEditField
        ResultLabel              matlab.ui.control.Label
        TnhButton                matlab.ui.control.Button
        N_input                  matlab.ui.control.NumericEditField
        NEditFieldLabel          matlab.ui.control.Label
        a_input                  matlab.ui.control.NumericEditField
        aLabel                   matlab.ui.control.Label
        Function_input           matlab.ui.control.EditField
        FUNCTIONEditFieldLabel   matlab.ui.control.Label
        b_input                  matlab.ui.control.NumericEditField
        bLabel                   matlab.ui.control.Label
        TCHPHNLabel              matlab.ui.control.Label
        GiithiunhmTab            matlab.ui.container.Tab
        ChcvThnhvinLabel_4       matlab.ui.control.Label
        ChcvThnhvinLabel_3       matlab.ui.control.Label
        ChcvThnhvinLabel_2       matlab.ui.control.Label
        ChcvThnhvinLabel         matlab.ui.control.Label
        ChcvThkcngtyFLCLabel     matlab.ui.control.Label
        ChcvGimccngtyFLCLabel    matlab.ui.control.Label
        NhmlagLabel              matlab.ui.control.Label
        TrngHongAnhLabel         matlab.ui.control.Label
        LTnBoBoLabel             matlab.ui.control.Label
        QuchMinhThnhLabel        matlab.ui.control.Label
        TrnQucHuyLabel           matlab.ui.control.Label
        DonNguynVLabel           matlab.ui.control.Label
        TrnKhnhDLabel            matlab.ui.control.Label
        Image5                   matlab.ui.control.Image
        Image6                   matlab.ui.control.Image
        Image4                   matlab.ui.control.Image
        Image3                   matlab.ui.control.Image
        Image2                   matlab.ui.control.Image
        Image                    matlab.ui.control.Image
    end

    
    methods (Access = public)
        function y= tichphanhinhthang(~,fx,a,b,N)
            h=abs(b-a)/N;
            i=1:N-1;
            y=(h/2)*(fx(a)+fx(b)+2*sum(fx(a+i*h)));
        end
        
        function y= tichphanSimpson1phan3(~,fx,a,b,N)
            h=abs(b-a)/N;
            i=1:N-1;
            S=fx(a+i*h);
            y=(h/3)*(fx(a)+fx(b)+4*sum(S(2:2:N-1))+2*sum(S(1:2:N-1)));
        end
        
        function y= tichphanSimpson3phan8(~,fx,a,b,N)
            h=abs(b-a)/N;
            i=1:N-1;
            S=fx(a+i*h);
            j=3:3:N-1;
            S3=sum(S(3:3:N-1));
            S(j)=[];
            S0=sum(S);
            y=(3*h/8)*(fx(a)+fx(b)+3*S0+2*S3);
        end
        function dathuc =lagrange(~,x_array,y_array)
             syms x;
             syms l [1 length(x_array)];
             for i=1:length(x_array)
                    x1=x_array;
                    x1(i)=[];
                    l(i)=prod(x-x1)/prod(x_array(i)-x1);
              end
            dathuc=sum(l.*y_array);
        end 

        
    end
    
    methods (Access = private)
        
        function result = xapXiTien(~ ,f, x_value, h_step, error)
            if strcmp(error , 'O(h)') == 1
                result = (- f(x_value) + f((x_value + h_step))) / h_step;
            else
                result = (-3*f(x_value) + 4*f((x_value + h_step)) - f((x_value + 2*h_step))) / (2*h_step);
            end
        end

        % Tinh xap xi tien voi dau vao la mang gia tri x y cua ham so 
        function result = xapXiTienWithArrayInput(~ ,x_array, y_array, x_value, error)
            for i = 1 : length(x_array)
                if x_value == x_array(i)                    
                    if strcmp(error , 'O(h)') == 1
                        if i == length(x_array)
                            result = 'Don''t have enough argument';
                        else
                            h_step = abs(x_array(i+1) - x_array(i));
                            result = (y_array(i+1) - y_array(i)) / h_step;
                        end
                    else
                        if i - 2 > length(x_array)
                            result = 'Don''t have enough argument';
                        else
                            h_step = abs(x_array(i+1) - x_array(i));
                            result = (-3*y_array(i) + 4*y_array(i+1) - y_array(i+2)) / (2*h_step);
                        end
                    end
                    return;
                end
            end 
            result = 'Don''t have x value in array';
        end
        
        % Tinh xap xi lui voi ham so f va buoc nhay h
        function result = xapXiLui(~, f, x_value, h_step, error)
            if strcmp(error , 'O(h)') == 1
                result = (f(x_value) - f((x_value - h_step))) / h_step;
            else
                result = (f(x_value - 2*h_step) - 4*f(x_value - h_step) + 3*f(x_value)) / (2*h_step);
            end
        end
        
        % Tinh xap xi tien voi dau vao la mang gia tri x y cua ham so 
        function result = xapXiLuiWithArrayInput(~ ,x_array, y_array, x_value, error)
            for i = 1 : length(x_array)
                if x_value == x_array(i)                  
                    if strcmp(error , 'O(h)') == 1
                        if i ==1
                            result = 'Don''t have enough argument';
                        else
                            h_step = abs(x_array(i) - x_array(i-1));
                            result = (y_array(i) - y_array(i-1)) / h_step;
                        end
                    else
                        if i-2 < 1
                            result = 'Don''t have enough argument';
                        else
                            h_step = abs(x_array(i) - x_array(i-1));
                            result = (y_array(i-2) -4*y_array(i-1) +3*y_array(i)) / (2*h_step);                    
                        end
                    end
                    return;
                end
            end
            result = 'Don''t have x value in array';
        end
        
        % Tinh xap xi trung tam voi ham so f va buoc nhay h
        function result = xapXiTrungTam(~ ,f, x_value, h_step, error)
            if strcmp(error , 'O(h)') == 1
                result = 'Don''t process';                
            else
                result = (f(x_value + h_step) - f(x_value - h_step)) / (2*h_step);
            end
        end        
        
        % Tinh xap xi tien voi dau vao la mang gia tri x y cua ham so 
        function result = xapXiTrungTamWithArrayInput(~ ,x_array, y_array, x_value, error)
            for i = 1 : length(x_array)
                if x_value == x_array(i)                    
                    if strcmp(error , 'O(h)') == 1
                        result = 'Don''t process';                        
                    else
                        if (i~= 1) && (i~= length(x_array))
                            h_step = abs(x_array(i+1) - x_array(i));
                            result = (y_array(i+1) - y_array(i-1)) / (2*h_step);    
                        else
                            result = 'Don''t have enough argument';
                        end
                    end 
                    return;
                end
            end
            result = 'Don''t have x value in array';
        end

        % HOI QUY
        % Tuyen tinh
        function [a0, a1] = f_linearRegression(~, x_array, y_array)
            sum_x = sum(x_array);
            sum_y = sum(y_array);
            sum_xy = sum((x_array .* y_array));
            sum_xSquare = sum((x_array.^2));
            len = length(x_array);
            
            numerator = (len * sum_xy) - (sum_x * sum_y);
            denominator = (len * sum_xSquare) - (sum_x^2);
        
            a1 = numerator / denominator;
        
            a0 = mean(y_array) - (a1 * mean(x_array));
        end

        % Da thuc
        function [c, b, a] = f_polynomialRegression(~, x_array, y_array, degreePolynomial)
            x_sigma = zeros(degreePolynomial);
            y_sigma = zeros(degreePolynomial, 1);
        
            for row_index = 0 : degreePolynomial
                for col_index = 0 : degreePolynomial
                    x_sigma(row_index + 1, col_index + 1) = ...
                        sum(x_array.^(row_index + col_index), 'all');
                end
            end
        
            for index = 0 : degreePolynomial
                y_sigma(index + 1) = sum((x_array.^index).*y_array, 'all');
            end
        
            result_array = x_sigma \ y_sigma;
            c = result_array(1);
            b = result_array(2);
            a = result_array(3);
        end

        % Ham mu
        function [a, b] = f_exponentialRegression(~, x_array, y_array)
            y_array = log10(y_array);
            x_array = log10(x_array);
            len = length(x_array);
        
            sum_x = sum(x_array);
            sum_xSquare = sum(x_array .* x_array);
            sum_xy = sum(x_array .* y_array);
            sum_y = sum(y_array);
        
            Y = [sum_y; sum_xy];
            A = [len sum_x; sum_x sum_xSquare];
            X = A \ Y;
        
            a = 10 ^ X(1);
            b = X(2);
        end

        % Ham mu e
        function [a, b] = f_eExponentialRegression(~, x_array, y_array)
            y_array = log(y_array);
            len = length(x_array);
        
            sum_x = sum(x_array);
            sum_xSquare = sum(x_array .* x_array);
            sum_xy = sum(x_array .* y_array);
            sum_y = sum(y_array);
        
            Y = [sum_y; sum_xy];
            A = [len sum_x; sum_x sum_xSquare];
            X = A \ Y;
        
            a = exp(X(1));
            b = X(2);
        end
        %HET HOI QUY

        %NOI SUY 
         function dathuc = lagrangeNS(~,x_array, y_array)
                l=sym(ones(1,length(x_array)));
                syms x;
                for i= 1:length(x_array)
                    for j= 1:length(y_array)
                        if i~=j
                            l(i)=l(i)*(x-x_array(j))/(x_array(i)-x_array(j));
                        end
                    end
                end
                dathuc=simplify(l*y_array');   
          end  
          
          function p=newton(~,x,m,x_array,y_array)
            n=length(x_array);
            tiso=zeros(n-1,n-1);
            for r=1:n-1
                tiso(r,1)=(y_array(r+1)-y_array(r))/(x_array(r+1)-x_array(r));
            end 
            for c=2:n-1
                for r=1:n-c
                    tiso(r,c)=(tiso(r+1,c-1)-tiso(r,c-1))/(x_array(r+c)-x_array(r)); 
                end
            end 
            p=tiso(1,m);
                for i=m:-1:2
                    p=(x-x_array(i))*p+tiso(1,i-1);
                end
                p =simplify(y_array(1)+p*(x-x_array(1))); 
          end
         %HET NOI SUY
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TnhButton
        function ButtonPushed(app, event)
            a=app.a_input.Value;
            b=app.b_input.Value;
            N=app.N_input.Value;
            switch app.OPTIONINPUTListBox.Value
                case 'FUNCTION'
                    temp=0;
                    f=app.Function_input.Value;
                    fx=str2func(['@(x)',f]);
                case 'x,y'
                    temp=1;
                    x=str2num(app.xEditField.Value);
                    y=str2num(app.yEditField.Value);
                    a=x(1);
                    N=length(x)-1;
                    b=x(length(x));
                    dathuc=lagrange(app,x,y);
                    syms fx(x);
                    fx(x) =simplify(dathuc);
            end
        
            switch app.method_input.Value
                case 'hình thang'
                     y=tichphanhinhthang(app,fx,a,b,N);
                      app.Result.Value=double(vpa(y));
                case 'Simpson 1/3'
                    y=tichphanSimpson1phan3(app,fx,a,b,N);
                      app.Result.Value=double(vpa(y));
                case 'Simpson 3/8'
                    y=tichphanSimpson3phan8(app,fx,a,b,N);
                      app.Result.Value=double(vpa(y));
                case 'tính chính xác'
                    if temp==0
                    app.Result.Value=integral(fx,a,b);
                    end
            end
        end

        % Value changed function: typeOptionDropDown
        function typeOptionDropDownValueChanged(app, event)
            if strcmp(app.typeOptionDropDown.Value , 'Nhập hàm số') == 1
                app.NhpmngxLabel.Text = 'Nhập hàm số: ';
                app.xInputEditField.Placeholder = 'Ex: sin(x)';
                app.xInputEditField.Value = '';

                app.NhpmngyLabel.Text = 'Nhập bước h: ';
                app.yInputEditField.Placeholder = 'Ex: 0.001';
                app.yInputEditField.Value = '';
            else
                app.NhpmngxLabel.Text = 'Nhập x: ';
                app.xInputEditField.Placeholder = 'Ex: [x1, x2, x3]';
                app.xInputEditField.Value = '';

                app.NhpmngyLabel.Text = 'Nhập y: ';
                app.yInputEditField.Placeholder = 'Ex: [y1, y2, y3]';
                app.yInputEditField.Value = '';
            end
        end

        % Value changed function: figureOutButton
        function figureOutButtonValueChanged(app, event)
          
            if (isempty(app.xInputEditField.Value))||(isempty(app.yInputEditField.Value))
                result = 'Input must not empty';          
            else
                x_value = app.valueEditField.Value;
                error = app.errorOptionDropDown.Value;
                if strcmp(app.typeOptionDropDown.Value , 'Nhập hàm số') == 1
                   
                    f = str2func(['@(x)' app.xInputEditField.Value]);
                    h_step = str2double(app.yInputEditField.Value);
                    switch app.methodDropDown.Value
                        case 'Xấp xỉ tiến'
                            result = xapXiTien(app,f,x_value,h_step,error);
                        case 'Xấp xỉ lùi'
                            result = xapXiLui(app,f,x_value,h_step,error);
                        case 'Xấp xỉ trung tâm'
                            result = xapXiTrungTam(app,f,x_value,h_step,error);
                    end
                else
                    x_array = str2num(app.xInputEditField.Value);
                    y_array = str2num(app.yInputEditField.Value);
                    if (isempty(y_array))||(isempty(x_array))
                        result = 'Input is not an array';                     
                    elseif length(y_array)~=length(x_array)
                        result = 'Length of two array is not equal';
                    else
                        switch app.methodDropDown.Value
                            case 'Xấp xỉ tiến'
                                result = xapXiTienWithArrayInput(app ,x_array, y_array, x_value, error);
                            case 'Xấp xỉ lùi'
                                result = xapXiLuiWithArrayInput(app ,x_array, y_array, x_value, error);
                            case 'Xấp xỉ trung tâm'
                                result = xapXiTrungTamWithArrayInput(app ,x_array, y_array, x_value, error);
                        end
                    end                        
                end                              
            end
                
            if ischar(result)   
                app.resultEditField.FontColor = 'r';
                app.resultEditField.Value = result;                
            else    
                app.resultEditField.FontColor = 'k';             
                app.resultEditField.Value = num2str(result);                
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            x_array = str2num(app.XDataEditField.Value);
            y_array = str2num(app.YDataEditField.Value);
            switch app.TypeDropDown.Value
                case "Linear Regression"
                    [a0, a1] = f_linearRegression(app, x_array, y_array);
                    fx = @(x) a0 + (a1 .* x);
                case "Polynomial Regression"
                    if str2num(app.DegreeEditField.Value) >= 2
                        degreePolynomial = str2num(app.DegreeEditField.Value);
                        [c, b, a] = f_polynomialRegression(app, x_array, y_array, degreePolynomial);
                        fx = @(x) c + (b * x) + (a * x.^2);
                    else
                        msgbox('Enter the degree of the polynomial!', 'Error', 'error');
                    end
                case "Exponential Regression"
                    [a, b] = f_exponentialRegression(app, x_array, y_array);
                    fx = @(x) a * x .^ b;
                case "e_Exponential Regression"
                    [a, b] = f_eExponentialRegression(app, x_array, y_array);
                    fx = @(x) a * exp(b .* x);
            end
            hold(app.UIAxes, 'on');
                fplot(app.UIAxes, @(x) fx(x));
                plot(app.UIAxes, x_array, y_array, '.');
            hold(app.UIAxes, 'off');
        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            value = app.TypeDropDown.Value;
            if value == "Polynomial Regression"
                app.DegreeEditField.Visible = "on";
                app.DegreeEditFieldLabel.Visible = "on";
            end
        end

        % Button pushed function: TnsButton
        function TnsButtonPushed(app, event)
             x_array=str2num(app.xnsEditField.Value);
             y_array=str2num(app.ynsEditField.Value);
             m = str2num(app.EditField.Value);
             n = str2num(app.gtnsEditField.Value);
        switch app.ppnsDropDown.Value
            case 'Lagrange'
               y1=lagrangeNS(app,x_array,y_array);
               disp(y1);
               app.dtnsEditField.Value=string(vpa(y1));
                  
               fx1 = str2func(['@(x)' char(y1)]);
               b= fx1(n);
               app.kqnsEditField.Value=num2str(b); 
               title(app.UIAxes,"Đồ thị");
               fplot(app.UIAxesNS,@(x_array) fx1(x_array)); 
            case 'Newton'
               syms x;
               y = newton(app,x,m,x_array,y_array);
               app.dtnsEditField.Value=string(vpa(simplify(y)));

               fx1 = str2func(['@(x)' char(y)]);
               b= fx1(n);
               app.kqnsEditField.Value=num2str(b);  
               title(app.UIAxes,"Đồ thị","Color",'r')
               fplot(app.UIAxesNS,@(x_array) fx1(x_array)); 
        end
%         if app.bnsEditField.Value == ""
%                 msgbox('Vui lòng nhập bậc cần nội suy','Errol','errol')
%         end
        end

        % Value changed function: ppnsDropDown
        function ppnsDropDownValueChanged(app, event)
           value = app.ppnsDropDown.Value;
            if value == "Newton"
                app.EditField.Visible = "on";
                app.BcEditFieldLabel.Visible ="on";
            else
                app.EditField.Visible = "off";
                app.BcEditFieldLabel.Visible = "off";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NghimTab = uitab(app.TabGroup);
            app.NghimTab.Title = 'Nghiệm';
            app.NisuyTab = uitab(app.TabGroup);
            app.NisuyTab.Title = 'Nội suy';
            app.UIAxesNS = uiaxes(app.NisuyTab);
            title(app.UIAxesNS, 'Đồ Thị')
            xlabel(app.UIAxesNS, 'X')
            ylabel(app.UIAxesNS, 'Y')
            zlabel(app.UIAxesNS, 'Z')
            app.xEditField_2Label = uilabel(app.NisuyTab);
            app.xEditField_2Label.Text = 'x';
            app.xnsEditField = uieditfield(app.NisuyTab, 'text');
            app.xnsEditField.Placeholder = '[1 2 3]';
            app.xnsEditField.Value = '[0.1 0.2 0.3 0.4]';
            app.yEditField_2Label = uilabel(app.NisuyTab);
            app.yEditField_2Label.Text = 'y';
            app.ynsEditField = uieditfield(app.NisuyTab, 'text');
            app.ynsEditField.Placeholder = '[1 2 3]';
            app.ynsEditField.Value = '[0.09983  0.19867  0.29552  0.38942]';
            app.PHNGPHPLabel = uilabel(app.NisuyTab);
            app.PHNGPHPLabel.Text = 'PHƯƠNG PHÁP';
            app.ppnsDropDown = uidropdown(app.NisuyTab);
            app.ppnsDropDown.Items = {'Lagrange', 'Newton'};
            app.ppnsDropDown.ValueChangedFcn = createCallbackFcn(app, @ppnsDropDownValueChanged, true);
            app.ppnsDropDown.Value = 'Lagrange';
            app.ATHCLabel = uilabel(app.NisuyTab);
            app.ATHCLabel.Text = 'ĐA THỨC';
            app.dtnsEditField = uieditfield(app.NisuyTab, 'text');
            app.GitrnisuyEditFieldLabel = uilabel(app.NisuyTab);
            app.GitrnisuyEditFieldLabel.Text = 'Giá trị nội suy';
            app.gtnsEditField = uieditfield(app.NisuyTab, 'text');
            app.gtnsEditField.Value = '1';
            app.TnsButton = uibutton(app.NisuyTab, 'push');
            app.TnsButton.ButtonPushedFcn = createCallbackFcn(app, @TnsButtonPushed, true);
            app.TnsButton.Text = 'Tính';
            app.KtqunisuyEditFieldLabel = uilabel(app.NisuyTab);
            app.KtqunisuyEditFieldLabel.Text = 'Kết quả nội suy';
            app.kqnsEditField = uieditfield(app.NisuyTab, 'text');
            app.BcEditFieldLabel = uilabel(app.NisuyTab);
            app.BcEditFieldLabel.Visible = 'off';
            app.BcEditFieldLabel.Text = 'Bậc';
            app.EditField = uieditfield(app.NisuyTab, 'text');
            app.EditField.Visible = 'off';
            app.NISUYLabel = uilabel(app.NisuyTab);
            app.NISUYLabel.Text = 'NỘI SUY';
            app.HiquyTab = uitab(app.TabGroup);
            app.HiquyTab.Title = 'Hồi quy';
            app.UIAxes = uiaxes(app.HiquyTab);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.RunButton = uibutton(app.HiquyTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.XDataEditFieldLabel = uilabel(app.HiquyTab);
            app.XDataEditFieldLabel.Text = 'X Data';
            app.XDataEditField = uieditfield(app.HiquyTab, 'text');
            app.YDataEditFieldLabel = uilabel(app.HiquyTab);
            app.YDataEditFieldLabel.Text = 'Y Data';
            app.YDataEditField = uieditfield(app.HiquyTab, 'text');
            app.TypeDropDownLabel = uilabel(app.HiquyTab);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.HiquyTab);
            app.TypeDropDown.Items = {'Linear Regression', 'Polynomial Regression', 'Exponential Regression', 'e_Exponential Regression'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.TypeDropDown.Value = 'Linear Regression';
            app.DegreeEditFieldLabel = uilabel(app.HiquyTab);
            app.DegreeEditFieldLabel.Visible = 'off';
            app.DegreeEditFieldLabel.Text = 'Degree';
            app.DegreeEditField = uieditfield(app.HiquyTab, 'text');
            app.DegreeEditField.Visible = 'off';
            app.derivativeTab = uitab(app.TabGroup);
            app.derivativeTab.Title = 'Đạo hàm';
            app.cnhpinputLabel = uilabel(app.derivativeTab);
            app.cnhpinputLabel.Text = 'Cách nhập input';
            app.typeOptionDropDown = uidropdown(app.derivativeTab);
            app.typeOptionDropDown.Items = {'Nhập dữ liệu ', 'Nhập hàm số'};
            app.typeOptionDropDown.ValueChangedFcn = createCallbackFcn(app, @typeOptionDropDownValueChanged, true);
            app.typeOptionDropDown.Value = 'Nhập dữ liệu ';
            app.NhpmngxLabel = uilabel(app.derivativeTab);
            app.NhpmngxLabel.Text = 'Nhập mảng x :';
            app.xInputEditField = uieditfield(app.derivativeTab, 'text');
            app.xInputEditField.Placeholder = 'Ex: [x1, x2, x3]';
            app.NhpmngyLabel = uilabel(app.derivativeTab);
            app.NhpmngyLabel.Text = 'Nhập mảng  y :';
            app.yInputEditField = uieditfield(app.derivativeTab, 'text');
            app.yInputEditField.Placeholder = 'Ex: [y1, y2, y3]';
            app.cnhpinputLabel_2 = uilabel(app.derivativeTab);
            app.cnhpinputLabel_2.Text = 'Kiểu sai số:';
            app.errorOptionDropDown = uidropdown(app.derivativeTab);
            app.errorOptionDropDown.Items = {'O(h)', 'O(h^2)'};
            app.errorOptionDropDown.Value = 'O(h)';
            app.PhngphpohmLabel = uilabel(app.derivativeTab);
            app.PhngphpohmLabel.Text = 'Phương pháp đạo hàm';
            app.methodDropDown = uidropdown(app.derivativeTab);
            app.methodDropDown.Items = {'Xấp xỉ tiến', 'Xấp xỉ lùi', 'Xấp xỉ trung tâm'};
            app.methodDropDown.Value = 'Xấp xỉ tiến';
            app.GitrcntnhohmLabel = uilabel(app.derivativeTab);
            app.GitrcntnhohmLabel.Text = 'Giá trị cần tính:';
            app.valueEditField = uieditfield(app.derivativeTab, 'numeric');
            app.figureOutButton = uibutton(app.derivativeTab, 'state');
            app.figureOutButton.ValueChangedFcn = createCallbackFcn(app, @figureOutButtonValueChanged, true);
            app.figureOutButton.Text = 'Tính';
            app.KtquEditFieldLabel_2 = uilabel(app.derivativeTab);
            app.KtquEditFieldLabel_2.Tag = 'resultTag';
            app.KtquEditFieldLabel_2.Text = 'Kết quả';
            app.resultEditField = uieditfield(app.derivativeTab, 'text');
            app.OHMLabel = uilabel(app.derivativeTab);
            app.OHMLabel.Text = 'ĐẠO HÀM';
            app.TchphnTab = uitab(app.TabGroup);
            app.TchphnTab.Title = 'Tích phân';
            app.TCHPHNLabel = uilabel(app.TchphnTab);
            app.TCHPHNLabel.Text = 'TÍCH PHÂN';
            app.bLabel = uilabel(app.TchphnTab);
            app.bLabel.Text = 'b';
            app.b_input = uieditfield(app.TchphnTab, 'numeric');
            app.b_input.Value = 1;
            app.FUNCTIONEditFieldLabel = uilabel(app.TchphnTab);
            app.FUNCTIONEditFieldLabel.Text = 'FUNCTION';
            app.Function_input = uieditfield(app.TchphnTab, 'text');
            app.aLabel = uilabel(app.TchphnTab);
            app.aLabel.Text = 'a';
            app.a_input = uieditfield(app.TchphnTab, 'numeric');
            app.NEditFieldLabel = uilabel(app.TchphnTab);
            app.NEditFieldLabel.Text = 'N';
            app.N_input = uieditfield(app.TchphnTab, 'numeric');
            app.N_input.Value = 10;
            app.TnhButton = uibutton(app.TchphnTab, 'push');
            app.TnhButton.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.TnhButton.Text = 'Tính';
            app.ResultLabel = uilabel(app.TchphnTab);
            app.ResultLabel.Text = 'Result';
            app.Result = uieditfield(app.TchphnTab, 'numeric');
            app.XEditFieldLabel = uilabel(app.TchphnTab);
            app.XEditFieldLabel.Text = 'X';
            app.xEditField = uieditfield(app.TchphnTab, 'text');
            app.xEditField.Value = '0';
            app.YEditFieldLabel = uilabel(app.TchphnTab);
            app.YEditFieldLabel.Text = 'Y';
            app.yEditField = uieditfield(app.TchphnTab, 'text');
            app.yEditField.Value = '0';
            app.OPTIONINPUTListBoxLabel = uilabel(app.TchphnTab);
            app.OPTIONINPUTListBoxLabel.Text = 'OPTION INPUT';
            app.OPTIONINPUTListBox = uilistbox(app.TchphnTab);
            app.OPTIONINPUTListBox.Items = {'FUNCTION', 'x,y'};
            app.OPTIONINPUTListBox.Value = 'FUNCTION';
            app.MethodLabel = uilabel(app.TchphnTab);
            app.MethodLabel.Text = 'Option';
            app.method_input = uidropdown(app.TchphnTab);
            app.method_input.Items = {'hình thang', 'Simpson 1/3', 'Simpson 3/8', 'tính chính xác'};
            app.method_input.Value = 'hình thang';
            app.LununhphmvththmduchoccduvnhngngcchoknhaIloveYouLabel = uilabel(app.TchphnTab);
            app.LununhphmvththmduchoccduvnhngngcchoknhaIloveYouLabel.Text = 'Lưu ý: nếu nhập hàm vô thì thêm dấu . cho các dấu +,-,*,/ và nhớ đóng ngược cho kĩ nha. IloveYou 😘';
            app.GiithiunhmTab = uitab(app.TabGroup);
            app.GiithiunhmTab.Title = 'Giới thiệu nhóm';
            app.Image = uiimage(app.GiithiunhmTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'img', 'Dư.png');
            app.Image2 = uiimage(app.GiithiunhmTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'img', 'Huy.png');
            app.Image3 = uiimage(app.GiithiunhmTab);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'img', 'Võ.png');
            app.Image4 = uiimage(app.GiithiunhmTab);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'img', 'Thịnh1.png');
            app.Image6 = uiimage(app.GiithiunhmTab);
            app.Image6.ImageSource = fullfile(pathToMLAPP, 'img', 'Anh.png');
            app.Image5 = uiimage(app.GiithiunhmTab);
            app.Image5.ImageSource = fullfile(pathToMLAPP, 'img', 'Bảo.png');
            app.TrnKhnhDLabel = uilabel(app.GiithiunhmTab);
            app.TrnKhnhDLabel.Text = 'Trần Khánh Dư';
            app.DonNguynVLabel = uilabel(app.GiithiunhmTab);
            app.DonNguynVLabel.Text = 'Doãn Nguyễn Võ';
            app.TrnQucHuyLabel = uilabel(app.GiithiunhmTab);
            app.TrnQucHuyLabel.Text = 'Trần Quốc Huy';
            app.QuchMinhThnhLabel = uilabel(app.GiithiunhmTab);
            app.QuchMinhThnhLabel.Text = 'Quách Minh Thịnh';
            app.LTnBoBoLabel = uilabel(app.GiithiunhmTab);
            app.LTnBoBoLabel.Text = 'Lê Tấn Bảo Bảo';
            app.TrngHongAnhLabel = uilabel(app.GiithiunhmTab);
            app.TrngHongAnhLabel.Text = 'Trương Hoàng Anh';
            app.NhmlagLabel = uilabel(app.GiithiunhmTab);
            app.NhmlagLabel.Text = 'Nhóm lùa gà';
            app.ChcvGimccngtyFLCLabel = uilabel(app.GiithiunhmTab);
            app.ChcvGimccngtyFLCLabel.Text = 'Chức vụ: Giám đốc công ty FLC';
            app.ChcvThkcngtyFLCLabel = uilabel(app.GiithiunhmTab);
            app.ChcvThkcngtyFLCLabel.Text = 'Chức vụ: Thư kí công ty FLC';
            app.ChcvThnhvinLabel = uilabel(app.GiithiunhmTab);
            app.ChcvThnhvinLabel.Text = 'Chức vụ: Thành viên';
            app.ChcvThnhvinLabel_2 = uilabel(app.GiithiunhmTab);
            app.ChcvThnhvinLabel_2.Text = 'Chức vụ: Thành viên';
            app.ChcvThnhvinLabel_3 = uilabel(app.GiithiunhmTab);
            app.ChcvThnhvinLabel_3.Text = 'Chức vụ: Thành viên';
            app.ChcvThnhvinLabel_4 = uilabel(app.GiithiunhmTab);
            app.ChcvThnhvinLabel_4.Text = 'Chức vụ: Thành viên';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end